classdef Image_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        stop                matlab.ui.control.Button
        start               matlab.ui.control.Button
        startTrace          matlab.ui.control.Button
        dot                 matlab.ui.control.EditField
        tef                 matlab.ui.control.EditField
        xce                 matlab.ui.control.EditField
        yce                 matlab.ui.control.EditField
        stopTrace           matlab.ui.control.Button
        dex                 matlab.ui.control.EditField
        dey                 matlab.ui.control.EditField
        tiempo_integracion  matlab.ui.control.EditField
        text8               matlab.ui.control.Label
        text9               matlab.ui.control.Label
        text10              matlab.ui.control.Label
        text11              matlab.ui.control.Label
        text12              matlab.ui.control.Label
        text13              matlab.ui.control.Label
        text14              matlab.ui.control.Label
        text15              matlab.ui.control.Label
        cuy                 matlab.ui.control.Label
        text17              matlab.ui.control.Label
        text18              matlab.ui.control.Label
        text19              matlab.ui.control.Label
        text565             matlab.ui.control.Label
        stdcounts           matlab.ui.control.Label
        text22              matlab.ui.control.Label
        meancounts          matlab.ui.control.Label
        text24              matlab.ui.control.Label
        cux                 matlab.ui.control.Label
        cnt                 matlab.ui.control.Label
        tin                 matlab.ui.control.EditField
        text25              matlab.ui.control.Label
        text27              matlab.ui.control.Label
        text28              matlab.ui.control.Label
        scn                 matlab.ui.control.Label
        text30              matlab.ui.control.Label
        stp                 matlab.ui.control.Label
        text32              matlab.ui.control.Label
        GlobalStop          matlab.ui.control.Button
        axes10              matlab.ui.control.UIAxes
        zSliderLabel        matlab.ui.control.Label
        cuz                 matlab.ui.control.Label
        text18_2            matlab.ui.control.Label
        axes11              matlab.ui.control.UIAxes
        axes12              matlab.ui.control.UIAxes
        zSlider             matlab.ui.control.Slider
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Image_OpeningFcn(app)
            global Gstop S
            
            daq.reset; S = daq.createSession('ni');
            co0 = addAnalogOutputChannel(S, 'LAB', 'ao0', 'Voltage');
            co1 = addAnalogOutputChannel(S, 'LAB', 'ao1', 'Voltage');
            C02 = addAnalogOutputChannel(S, 'LAB', 'ao2', 'Voltage');
            addAnalogInputChannel(S,'LAB', 0, 'Voltage'); addCounterInputChannel(S,'LAB', 0, 'EdgeCount');
            Gstop = 1;
        end

        % Button pushed function: GlobalStop
        function GlobalStop_Callback(app, event)
            global Gstop S
            if Gstop == 0;
                CurrentX = str2double(app.cux.Text)/8;
                CurrentY = str2double(app.cuy.Text)/8;
                CurrentZ = app.zSlider.Value/8;
                datax = 0:CurrentX/10:CurrentX;
                datay = 0:CurrentY/10:CurrentY;
                dataz = 0:CurrentZ/10:CurrentZ;
                datax = flip(datax);
                datay = flip(datay);
                dataz = flip(dataz);
                if CurrentX == 0; datax = zeros(1,length(datay)); end
                if CurrentY == 0; datay = zeros(1,length(datax)); end
                if CurrentZ == 0; dataz = zeros(1,length(datax)); end
                for i = 1:max(size(datax))
                    datax_A = ones(1,5)*datax(i);
                    datay_A = ones(1,5)*datay(i);
                    dataz_A = ones(1,5)*dataz(i);
                    queueOutputData(S,[datax_A' datay_A' dataz_A']);
                    S.startForeground();
                    app.cux.Text = num2str(round(8*datax(i),3));
                    app.cuy.Text = num2str(round(8*datay(i),3));
                    app.cuz.Text = num2str(round(8*dataz(i),3));
                    app.zSlider.Value = 8*dataz(i);
                    
                end
                clc
                clear all
                close all
            end
            Gstop = 0;
        end

        % Button pushed function: startTrace
        function startTrace_Callback(app, event)
            global arct Gstop S;
            import java.awt.Robot;
            import java.awt.event.*;
            mouse = Robot;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            CurrentX = str2double(app.cux.Text);
            CurrentY = str2double(app.cuy.Text);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            X1_A = str2double(app.xce.Value); Y1_A =  str2double(app.yce.Value);
            DX_A = str2double(app.dex.Value); DY_A =  str2double(app.dey.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            cnt=0;
            arct=1;
            Trace_lenght = 25;
            cnt=1:Trace_lenght;
            ene=0;
            alltrace = 0;
            row = 0;
            xcen=CurrentX/8;
            ycen=CurrentY/8;
            while and(arct==1, Gstop==1)
                N_A1 = str2double(app.dot.Value);
                N_A = int64(N_A1);
                Tiempo_int = str2double(app.tin.Value);
                datx_A = ones(1,round(N_A*Tiempo_int))*xcen;
                daty_A = ones(1,round(N_A*Tiempo_int))*ycen;
                datz_A = ones(1,round(N_A*Tiempo_int))*app.zSlider.Value/8;
                row = row+1;
                ene=ene+1;
                queueOutputData(S,[datx_A' daty_A' datz_A']);
                [data, time] = S.startForeground();
                A_data = data(2:end,2);
                A_time = time (2:end);
                A_vect = A_data./A_time;
                cnt(ene)=mean(A_vect);
                alltrace = [alltrace cnt(ene)];
                Int_time = time(end);
                if ene == Trace_lenght ene = 0; end
                if row >= Trace_lenght
                    cntaverage = mean(cnt);
                    cntstd = std(cnt);
                    app.meancounts.Text = num2str(round(cntaverage));
                    app.stdcounts.Text = num2str(round(cntstd));
                end
                app.tiempo_integracion.Value = num2str(1000*Int_time);
                plot(app.axes12,cnt(1:Trace_lenght));
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                cumouse = get(app.axes10, 'CurrentPoint');
                Xcupos = cumouse(1);
                Ycupos = cumouse (3);
                if and(Xcupos>(X1_A-(DX_A/2)), Xcupos<(X1_A+(DX_A/2))) && and(Ycupos>(Y1_A-(DY_A/2)), Ycupos<(Y1_A+(DY_A/2)))
                    mouse.mousePress(InputEvent.BUTTON3_MASK);
                    mouse.mouseRelease(InputEvent.BUTTON3_MASK);
                    xcen = Xcupos/8;
                    ycen = Ycupos/8;
                end
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.cux.Text = num2str(round(8*xcen,3));
                app.cuy.Text = num2str(round(8*ycen,3));
                app.cuz.Text = num2str(round(app.zSlider.Value,3));
                drawnow;
                if not(Gstop); StopProgram(); end
            end
        end

        % Button pushed function: start
        function start_Callback(app, event)
            global arc datax_A datay_A dataz_A Gstop S
            
            X1_A = str2double(app.xce.Value); Y1_A =  str2double(app.yce.Value);
            DX_A = str2double(app.dex.Value); DY_A =  str2double(app.dey.Value);
            N_A1 = str2double(app.dot.Value); N_A = int64(N_A1);
            tpause1_A = str2double(app.tef.Value);
            Tiempo_int = str2double(app.tin.Value);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if or(or(((X1_A + DX_A*0.5)> 80), ((X1_A - DX_A*0.5)< 0)),or(((Y1_A + DY_A*0.5)> 80), ((Y1_A - DY_A*0.5)< 0))) return; end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ax=1:1:N_A;
            b=1:1:N_A;
            arc= 1;
            S.Rate=1000;
            
            datax = linspace((X1_A/8)-(DX_A/16),(X1_A/8)+(DX_A/16),N_A)';
            datay = linspace((Y1_A/8)-(DY_A/16),(Y1_A/8)+(DY_A/16),N_A)';
            paso = (datax(2)-datax(1))*8;
            
            datax_A = ones(1,round(N_A*Tiempo_int))*datax(1);
            datay_A = ones(1,round(N_A*Tiempo_int))*datay(1);
            dataz_A = ones(1,round(N_A*Tiempo_int))*app.zSlider.Value/8;
            queueOutputData(S,[datax_A'  datay_A' dataz_A']);
            A_zeros = 0;
            a=ones(N_A);
            [data, time] = S.startForeground();
            A_data = data(:,2);
            A_zeros = A_data(end);
            a = a *A_zeros/time(end);
            for i = 1:N_A
                for j = 1:N_A
                    pause(tpause1_A/1000);
                    datax_A = ones(1,round(N_A*Tiempo_int))*datax(i);
                    datay_A = ones(1,round(N_A*Tiempo_int))*datay(j);
                    dataz_A = ones(1,round(N_A*Tiempo_int))*app.zSlider.Value/8;
                    queueOutputData(S,[datax_A' datay_A' dataz_A']);
                    input(i,j,a,b,paso);
                    stop(S)
                    if not(and(arc,Gstop)); break; end
                    drawnow();
                end
                if not(and(arc,Gstop)); break; end
            end
            
            function input(x,y,vec1,vec2,paso)
                [data, time] = S.startForeground();
                A_data = data(2:end,2);
                A_time = time(2:end);
                A_vect = A_data./A_time;
                A_zeros = mean(A_vect);
                Int_time = time(end);
                cnt=A_zeros;
                vec1(x,y)= cnt;
                vec2(y)= cnt;
                std_A_zeros = std(A_vect);
                h = pcolor(app.axes10,8*datax,8*datax,vec1); set(h, 'EdgeColor', 'none');
                colorbar(app.axes10)
                colormap(app.axes10,'jet')
                plot(app.axes11,8*datax,vec2)
                a=vec1; b=vec2;
                app.cux.Text = num2str(round(8*datax(x),3));
                app.cuy.Text = num2str(round(8*datay(y),3));
                app.cuz.Text = num2str(round(app.zSlider.Value,3));
                app.cnt.Text =  num2str(round(cnt));
                app.tiempo_integracion.Value = num2str(1000*Int_time);
                app.scn.Text = num2str(round(std_A_zeros));
                app.stp.Text = num2str(paso*1000);
            end
            if not(Gstop); app.GlobalStop_Callback(); end
        end

        % Button pushed function: stopTrace
        function stopTrace_Callback(app, event)
            global arct
            arct=0;
        end

        % Button pushed function: stop
        function stop_Callback(app, event)
            global arc
            arc=0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Image';
            app.stop = uibutton(app.figure1, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop.Text = 'Stop Scan';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_Callback, true);
            app.start.Text = 'Start Scan';
            app.startTrace = uibutton(app.figure1, 'push');
            app.startTrace.ButtonPushedFcn = createCallbackFcn(app, @startTrace_Callback, true);
            app.startTrace.Text = 'Start Trace';
            app.dot = uieditfield(app.figure1, 'text');
            app.dot.Value = '51';
            app.tef = uieditfield(app.figure1, 'text');
            app.tef.Value = '5';
            app.xce = uieditfield(app.figure1, 'text');
            app.xce.Value = '40';
            app.yce = uieditfield(app.figure1, 'text');
            app.yce.Value = '40';
            app.stopTrace = uibutton(app.figure1, 'push');
            app.stopTrace.ButtonPushedFcn = createCallbackFcn(app, @stopTrace_Callback, true);
            app.stopTrace.Text = 'Stop Trace';
            app.dex = uieditfield(app.figure1, 'text');
            app.dex.Value = '80';
            app.dey = uieditfield(app.figure1, 'text');
            app.dey.Value = '80';
            app.tiempo_integracion = uieditfield(app.figure1, 'text');
            app.tiempo_integracion.Value = '25';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Setteling time';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Integration time';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'X Center';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'Scan X';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'Y Center';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'Scan Y';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'Número de puntos';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'ms';
            app.cuy = uilabel(app.figure1);
            app.cuy.Text = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'Current X';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Current Y';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Current counts/s';
            app.text565 = uilabel(app.figure1);
            app.text565.Text = 'Mean';
            app.stdcounts = uilabel(app.figure1);
            app.stdcounts.Text = '0';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'Std';
            app.meancounts = uilabel(app.figure1);
            app.meancounts.Text = '0';
            app.text24 = uilabel(app.figure1);
            app.text24.Text = 'ms';
            app.cux = uilabel(app.figure1);
            app.cux.Text = '0';
            app.cnt = uilabel(app.figure1);
            app.cnt.Text = '0';
            app.tin = uieditfield(app.figure1, 'text');
            app.tin.Value = '1';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'Control Int.Time';
            app.text27 = uilabel(app.figure1);
            app.text27.Text = 'microns';
            app.text28 = uilabel(app.figure1);
            app.text28.Text = 'microns';
            app.scn = uilabel(app.figure1);
            app.scn.Text = '0';
            app.text30 = uilabel(app.figure1);
            app.text30.Text = 'Std counts/s';
            app.stp = uilabel(app.figure1);
            app.stp.Text = '0';
            app.text32 = uilabel(app.figure1);
            app.text32.Text = 'Step (nm)';
            app.GlobalStop = uibutton(app.figure1, 'push');
            app.GlobalStop.ButtonPushedFcn = createCallbackFcn(app, @GlobalStop_Callback, true);
            app.GlobalStop.Text = 'Stop Program';
            app.axes10 = uiaxes(app.figure1);
            title(app.axes10, '')
            xlabel(app.axes10, 'X (microns)')
            ylabel(app.axes10, 'Y(microns)')
            app.zSliderLabel = uilabel(app.figure1);
            app.zSliderLabel.Text = '';
            app.cuz = uilabel(app.figure1);
            app.cuz.Text = '0';
            app.text18_2 = uilabel(app.figure1);
            app.text18_2.Text = 'Z';
            app.zSlider = uislider(app.figure1);
            app.zSlider.Limits = [0 80];
            app.axes11 = uiaxes(app.figure1);
            title(app.axes11, '')
            xlabel(app.axes11, 'X (microns)')
            ylabel(app.axes11, 'Counts')
            app.axes12 = uiaxes(app.figure1);
            title(app.axes12, 'Title')
            xlabel(app.axes12, 'X (microns)')
            ylabel(app.axes12, 'Counts/s')
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Image_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end