classdef Image_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        axes10      matlab.ui.control.UIAxes
        axes11      matlab.ui.control.UIAxes
        axes12      matlab.ui.control.UIAxes
        stop        matlab.ui.control.Button
        start       matlab.ui.control.Button
        startTrace  matlab.ui.control.Button
        dot         matlab.ui.control.EditField
        tef         matlab.ui.control.EditField
        xce         matlab.ui.control.EditField
        yce         matlab.ui.control.EditField
        stopTrace   matlab.ui.control.Button
        dex         matlab.ui.control.EditField
        dey         matlab.ui.control.EditField
        tin         matlab.ui.control.EditField
        text8       matlab.ui.control.Label
        text9       matlab.ui.control.Label
        text10      matlab.ui.control.Label
        text11      matlab.ui.control.Label
        text12      matlab.ui.control.Label
        text13      matlab.ui.control.Label
        text14      matlab.ui.control.Label
        text15      matlab.ui.control.Label
        cuy         matlab.ui.control.Label
        text17      matlab.ui.control.Label
        text18      matlab.ui.control.Label
        text19      matlab.ui.control.Label
        text565     matlab.ui.control.Label
        stdcounts   matlab.ui.control.Label
        text22      matlab.ui.control.Label
        meancounts  matlab.ui.control.Label
        text24      matlab.ui.control.Label
        cux         matlab.ui.control.Label
        cnt         matlab.ui.control.Label
        text25      matlab.ui.control.Label
        text26      matlab.ui.control.Label
        stp         matlab.ui.control.EditField
        scn         matlab.ui.control.EditField
        text29      matlab.ui.control.Label
        tco         matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function scn_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function stp_CreateFcn(app, hObject, eventdata, handles)
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Image_OpeningFcn(app)
%             app.output = hObject;
%             guidata(hObject, handles);
%             global IMG;
%             IMG = ClassIMG;
%             IMG.h = handles;
%             IMG.start
        end

        % Value changed function: dex
        function dex_Callback(app, event)
            global DX;
            DX = app.dex.Value;
            guidata(hObject, handles);
        end

        % Value changed function: dey
        function dey_Callback(app, event)
            global DY;
            DY = app.dey.Value;
            guidata(hObject, handles);
        end

        % Value changed function: dot
        function dot_Callback(app, event)
            global N;
            N = app.dot.Value;
            guidata(hObject, handles);
        end

        % Button pushed function: startTrace
        function startTrace_Callback(app, event)
            global IMG
            IMG.Trace
        end

        % Button pushed function: start
        function start_Callback(app, event)
            global IMG
            IMG.Image
        end

        % Button pushed function: stopTrace
        function stopTrace_Callback(app, event)
            global IMG;
            IMG.StopTrace
        end

        % Button pushed function: stop
        function stop_Callback(app, event)
            global IMG
            IMG.Stop
        end

        % Value changed function: tef
        function tef_Callback(app, event)
            global tpause1;
            tpause1 = app.tef.Value/1000;
            guidata(hObject, handles);
        end

        % Value changed function: tin
        function tin_Callback(app, event)
            global tpause2;
            tpause2 = app.tin.Value;
            guidata(hObject, handles);
        end

        % Value changed function: xce
        function xce_Callback(app, event)
            global X1;
            X1 =app.xce.Value;
            guidata(hObject, handles);
        end

        % Value changed function: yce
        function yce_Callback(app, event)
            global Y1;
            Y1 =app.yce.Value;
            guidata(hObject, handles);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Image';
            app.axes10 = uiaxes(app.figure1);
            app.axes11 = uiaxes(app.figure1);
            app.axes12 = uiaxes(app.figure1);
            app.stop = uibutton(app.figure1, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stop_Callback, true);
            app.stop.Text = 'Stop Scan';
            app.start = uibutton(app.figure1, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @start_Callback, true);
            app.start.Text = 'Start Scan';
            app.startTrace = uibutton(app.figure1, 'push');
            app.startTrace.ButtonPushedFcn = createCallbackFcn(app, @startTrace_Callback, true);
            app.startTrace.Text = 'Start Trace';
            app.dot = uieditfield(app.figure1, 'text');
            app.dot.ValueChangedFcn = createCallbackFcn(app, @dot_Callback, true);
            app.dot.Value = '50';
            app.tef = uieditfield(app.figure1, 'text');
            app.tef.ValueChangedFcn = createCallbackFcn(app, @tef_Callback, true);
            app.tef.Value = '5';
            app.xce = uieditfield(app.figure1, 'text');
            app.xce.ValueChangedFcn = createCallbackFcn(app, @xce_Callback, true);
            app.xce.Value = '10';
            app.yce = uieditfield(app.figure1, 'text');
            app.yce.ValueChangedFcn = createCallbackFcn(app, @yce_Callback, true);
            app.yce.Value = '10';
            app.stopTrace = uibutton(app.figure1, 'push');
            app.stopTrace.ButtonPushedFcn = createCallbackFcn(app, @stopTrace_Callback, true);
            app.stopTrace.Text = 'Stop Trace';
            app.dex = uieditfield(app.figure1, 'text');
            app.dex.ValueChangedFcn = createCallbackFcn(app, @dex_Callback, true);
            app.dex.Value = '10';
            app.dey = uieditfield(app.figure1, 'text');
            app.dey.ValueChangedFcn = createCallbackFcn(app, @dey_Callback, true);
            app.dey.Value = '10';
            app.tin = uieditfield(app.figure1, 'text');
            app.tin.ValueChangedFcn = createCallbackFcn(app, @tin_Callback, true);
            app.tin.Value = '25';
            app.text8 = uilabel(app.figure1);
            app.text8.Text = 'Setteling time';
            app.text9 = uilabel(app.figure1);
            app.text9.Text = 'Integration time';
            app.text10 = uilabel(app.figure1);
            app.text10.Text = 'X Center';
            app.text11 = uilabel(app.figure1);
            app.text11.Text = 'Scan X';
            app.text12 = uilabel(app.figure1);
            app.text12.Text = 'Y Center';
            app.text13 = uilabel(app.figure1);
            app.text13.Text = 'Scan Y';
            app.text14 = uilabel(app.figure1);
            app.text14.Text = 'Número de puntos';
            app.text15 = uilabel(app.figure1);
            app.text15.Text = 'ms';
            app.cuy = uilabel(app.figure1);
            app.cuy.Text = '0';
            app.text17 = uilabel(app.figure1);
            app.text17.Text = 'Current X';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Current Y';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Current counts/s';
            app.text565 = uilabel(app.figure1);
            app.text565.Text = 'Mean';
            app.stdcounts = uilabel(app.figure1);
            app.stdcounts.Text = '0';
            app.text22 = uilabel(app.figure1);
            app.text22.Text = 'Std';
            app.meancounts = uilabel(app.figure1);
            app.meancounts.Text = '0';
            app.text24 = uilabel(app.figure1);
            app.text24.Text = 'ms';
            app.cux = uilabel(app.figure1);
            app.cux.Text = '0';
            app.cnt = uilabel(app.figure1);
            app.cnt.Text = '0';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'Step (nm)';
            app.text26 = uilabel(app.figure1);
            app.text26.Text = 'Std counts/s';
            app.stp = uieditfield(app.figure1, 'text');
            app.stp.Value = '0';
            app.scn = uieditfield(app.figure1, 'text');
            app.scn.Value = '0';
            app.text29 = uilabel(app.figure1);
            app.text29.Text = 'Control Int. Time';
            app.tco = uieditfield(app.figure1, 'text');
            app.tco.Value = '50';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Image_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end